classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        realtimeSummaryData     matlab.ui.container.Panel
        detail_info             matlab.ui.container.Panel
        sunsetVal               matlab.ui.control.Label
        sunriseVal              matlab.ui.control.Label
        windspeedVal            matlab.ui.control.Label
        precipVal               matlab.ui.control.Label
        humidityVal             matlab.ui.control.Label
        feelslikeVal            matlab.ui.control.Label
        sunsetCaption           matlab.ui.control.Label
        sunriseCaption          matlab.ui.control.Label
        windspeedCaption        matlab.ui.control.Label
        precipCaption           matlab.ui.control.Label
        humidityCaption         matlab.ui.control.Label
        feelslikeCaption        matlab.ui.control.Label
        descriptionVal          matlab.ui.control.Label
        weathericon             matlab.ui.control.Image
        tempVal                 matlab.ui.control.Label
        realtimeDetailedData    matlab.ui.container.Panel
        curr_windspeedVal       matlab.ui.control.Label
        curr_precipVal          matlab.ui.control.Label
        curr_humidityVal        matlab.ui.control.Label
        curr_tempVal            matlab.ui.control.Label
        curr_windspeedCaption   matlab.ui.control.Label
        curr_precipCaption      matlab.ui.control.Label
        curr_humidityCaption    matlab.ui.control.Label
        curr_tempCaption        matlab.ui.control.Label
        lbl_addr                matlab.ui.control.Label
        windSpeed_graph         matlab.ui.control.UIAxes
        precipitation_graph     matlab.ui.control.UIAxes
        humidity_graph          matlab.ui.control.UIAxes
        temp_graph              matlab.ui.control.UIAxes
        Input                   matlab.ui.container.Panel
        btn_update              matlab.ui.control.Button
        edit_location           matlab.ui.control.EditField
        LocationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_url = "https://weather.visualcrossing.com/VisualCrossingWebServices/rest/services/timeline/";
        API_KEY = '3KAJKHWT3UEMRQWF2ABKVVVZE';
        interval = 360; % interval to update the current data [s]
        Timer = timer;
        weatherData = struct();

        city = '98001'; % Zip code for Washington, USA
        address = '98001, USA';
        curr_date = '';
        todayIcon = 'clear-day'; % Description
        todayCondition = '' % Description
        todayDescription = '';
        todayTemp = '';
        todayFeelslike = '';
        todayHumidity = '';
        todayPrecip = '';
        todayWindSpeed = '';
        todaySunrise = '';
        todaySunset = '';
    end
    
    methods (Access = private)
        function formatted_time = getValidCurrentTime(~)
            current_time = datetime('now');
            current_hour = dateshift(current_time, 'start', 'hour');
            disp('getValidCurrentTime runs')
            time_str = datestr(current_hour, 'HH:00:00');
            formatted_time = datetime(time_str, 'InputFormat', 'HH:mm:ss', 'Format', 'HH:mm:ss');
        end

        function idx = findIndexFromCell(~, input_cell, val_to_find)
            idx = 0;
            for i = 1:numel(input_cell)
                % Compare each datetime with the specific datetime
                if input_cell{i} == val_to_find
                    % If found, store the index and break out of the loop
                    idx = i;
                    break;
                end
            end
        end

        function weatherData = fetchAndUpdateData(app, location)
            try
                app.curr_date = datestr(now, 'yyyy-mm-dd');
                api_url = sprintf('%s%s/%s?key=%s&include=hours', app.base_url, location, app.curr_date, app.API_KEY);                
                weatherData = webread(api_url);
            catch ME
                % Handle any errors that occur during the HTTP request
                error('Error fetching data from the API: %s', ME.message);
            end
        end

        function getYourCity(app)
            try
                data = webread('https://ipinfo.io/json/');
                app.city = data.postal;
                app.address = data.city;
            catch e
                disp('Failed to fetch initial city');
                disp(e.message);
                app.city = '98001';
                app.address = '98001, USA';
            end
            app.lbl_addr.Text = app.address; % Display the city in lbl_addr
%             fetchAndUpdateData(app, app.city); % Perform initial data fetch
        end
        
        function stat_data = display_weather_data(app, weather_data)
            app.address = weather_data.resolvedAddress;
            days = weather_data.days;
            app.todayIcon = days(1).icon;
            app.todayCondition = days(1).conditions;
            app.todayDescription = days(1).description;
            app.todayTemp = days(1).temp;
            app.todayFeelslike = days(1).feelslike;
            app.todayHumidity = days(1).humidity;
            app.todayPrecip = days(1).precip;
            app.todayWindSpeed = days(1).windspeed;
            app.todaySunrise = days(1).sunrise;
            app.todaySunset = days(1).sunset;

            hourlyData = days(1).hours;
            times = datetime({hourlyData.datetime}, 'InputFormat', 'HH:mm:ss', 'Format', 'HH:mm:ss');
            
            temps = [hourlyData.temp];
            humidities = [hourlyData.humidity];
            precips = [hourlyData.precip];
            wind_speeds = [hourlyData.windspeed];
            
            max_temp = max(temps);
            min_temp = min(temps);
            avg_temp = mean(temps);
            
            max_humidity = max(humidities);
            min_humidity = min(humidities);
            avg_humidity = mean(humidities);
            
            max_precip = max(precips);
            min_precip = min(precips);
            avg_precip = mean(precips);
            
            max_wind = max(wind_speeds);
            min_wind = min(wind_speeds);
            avg_wind = mean(wind_speeds);
            
            cla(app.temp_graph);
            cla(app.humidity_graph);
            cla(app.precipitation_graph)
            cla(app.windSpeed_graph)

            % Diplay Weather Info
            app.lbl_addr.Text = app.address;
            app.weathericon.ImageSource = sprintf('public/icons/PNG/1st Set - Color/%s.png', app.todayIcon);
            app.tempVal.Text = strcat(num2str(app.todayTemp), '°F');
            app.descriptionVal.Text = app.todayDescription;
            app.feelslikeVal.Text =num2str(app.todayFeelslike);
            app.humidityVal.Text = num2str(app.todayHumidity);
            app.precipVal.Text = num2str(app.todayPrecip);
            app.windspeedVal.Text = num2str(app.todayWindSpeed);
            app.sunriseVal.Text = app.todaySunrise;
            app.sunsetVal.Text = app.todaySunset;
            % Display current values
            curr_idx = times==getValidCurrentTime(app);
            app.curr_tempVal.Text = num2str(temps(curr_idx));
            app.curr_humidityVal.Text = num2str(humidities(curr_idx));
            app.curr_precipVal.Text = num2str(precips(curr_idx));
            app.curr_windspeedVal.Text = num2str(wind_speeds(curr_idx));

            % Temperature Plot
            temp_ax = app.temp_graph;
            plot(temp_ax, times, temps);
            title(temp_ax, 'Hourly Temperatures', 'r-');
            ylabel(temp_ax, 'Temperature (°C)');
            grid(temp_ax, "on");
            hold(temp_ax, 'on');
            temp_ax.YLim = [min_temp - 1, max_temp + 1];
            yline(temp_ax, min_temp,'--b',sprintf('Min Temp: %.2f°F', min_temp));
            yline(temp_ax, max_temp,'--r',sprintf('Max Temp: %.2f°F', max_temp));
            yline(temp_ax, avg_temp,'--k',sprintf('Avg Temp: %.2f°F', avg_temp));            
%             legend(temp_ax, 'Temperature', 'Max Temp', 'Min Temp', 'Avg Temp', 'Location', 'best


            % Humidity Plot
            humidity_ax = app.humidity_graph;
            plot(humidity_ax, times, humidities, 'b-');
            title(humidity_ax, 'Hourly Humidity');
            ylabel(humidity_ax, 'Humidity (%)');
            grid(humidity_ax, "on");
            hold(humidity_ax, 'on');
            humidity_ax.YLim = [min_humidity - 1, max_humidity + 1];
            yline(humidity_ax, min_humidity,'--b',sprintf('Min Humidity: %.2f%%', min_humidity));
            yline(humidity_ax, max_humidity,'--r',sprintf('Max Humidity: %.2f%%', max_humidity));
            yline(humidity_ax, avg_humidity,'--k',sprintf('Avg Humidity: %.2f%%', avg_humidity));
%             legend('Humidity', 'Max Humidity', 'Min Humidity', 'Avg Humidity', 'Location', 'best');
            
            
            % Precipitation Plot
            precipitation_ax = app.precipitation_graph;
            plot(precipitation_ax, times, precips, 'g-');
            title(precipitation_ax, 'Hourly Precipitation');
            ylabel(precipitation_ax, 'Precipitation (mm)');
            grid(precipitation_ax, "on");
            hold(precipitation_ax, 'on');
            precipitation_ax.YLim = [min_precip - 1, max_precip + 1];
            yline(precipitation_ax, min_precip,'--b',sprintf('Min Precipitation: %.2fmm', min_precip));
            yline(precipitation_ax, max_precip,'--r',sprintf('Max Precipitation: %.2fmm', max_precip));
            yline(precipitation_ax, avg_precip,'--k',sprintf('Avg Precipitation: %.2fmm', avg_precip));
%             legend('Precipitation', 'Max Precipitation', 'Min Precipitation', 'Avg Precipitation', 'Location', 'best');
            
            % Wind Speed Plot
            windSpeed_ax = app.windSpeed_graph;
            plot(windSpeed_ax, times, wind_speeds, 'm-');
            title(windSpeed_ax, 'Hourly Wind Speed');
            ylabel(windSpeed_ax, 'Wind Speed (km/h)');
            
            hold(windSpeed_ax, 'on');
            windSpeed_ax.YLim = [min_wind - 1, max_wind + 1];
            yline(windSpeed_ax, min_wind,'--b',sprintf('Min Wind Speed: %.2fkm/h', min_wind));
            yline(windSpeed_ax, max_wind,'--r',sprintf('Max Wind Speed: %.2fkm/h', max_wind));
            yline(windSpeed_ax, avg_wind,'--k',sprintf('Avg Wind Speed: %.2fkm/h', avg_wind));
%             legend('Wind Speed', 'Max Wind Speed', 'Min Wind Speed', 'Avg Wind Speed', 'Location', 'best');

            % Optionally, save the figure to a file
            % saveas(fig, 'weather_plot.png');
            
            % Optionally, display the figure
            % imshow('weather_plot.png');
            
            stat_data = {max_temp, min_temp, avg_temp, max_humidity, min_humidity, avg_humidity, max_precip, min_precip, avg_precip, max_wind, min_wind, avg_wind};
        end
        
        function timerCallback(app, obj, event)
            app.weatherData = fetchAndUpdateData(app, app.city);
            stat_data = display_weather_data(app, app.weatherData);
            disp(stat_data)
%             app.list_stat.Items(end+1) = stat_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFn(app)
            % Set up the timer
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.interval, ... % 3600 seconds = 1 hour
                "BusyMode","queue", ...
                'TimerFcn', @app.timerCallback); % Callback function to fetch data
        
            % Initial fetch to set parameter from IP and start the timer
            getYourCity(app);
            start(app.Timer);
        end

        % Button pushed function: btn_update
        function btn_update_pushed(app, event)
            location= app.edit_location.Value;
            if isempty(location)
                errordlg('Location is required!', 'Error');
                return;
            end
            app.city = location;
            stop(app.Timer)
            start(app.Timer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Input = uipanel(app.UIFigure);
            app.Input.Title = 'Input';
            app.LocationEditFieldLabel = uilabel(app.Input);
            app.LocationEditFieldLabel.Text = 'Location';
            app.edit_location = uieditfield(app.Input, 'text');
            app.btn_update = uibutton(app.Input, 'push');
            app.btn_update.ButtonPushedFcn = createCallbackFcn(app, @btn_update_pushed, true);
            app.btn_update.Text = 'Update';
            app.realtimeDetailedData = uipanel(app.UIFigure);
            app.realtimeDetailedData.Title = 'Real-time Hourly Weather Data';
            app.temp_graph = uiaxes(app.realtimeDetailedData);
            title(app.temp_graph, 'Daily Temperatures')
            xlabel(app.temp_graph, 'timestamp')
            ylabel(app.temp_graph, 'Temperature (°C)')
            zlabel(app.temp_graph, 'Z')
            app.humidity_graph = uiaxes(app.realtimeDetailedData);
            title(app.humidity_graph, 'Daily Humidity')
            xlabel(app.humidity_graph, 'timestamp')
            ylabel(app.humidity_graph, 'Humidity (%)')
            zlabel(app.humidity_graph, 'Z')
            app.precipitation_graph = uiaxes(app.realtimeDetailedData);
            title(app.precipitation_graph, 'Daily Precipitation')
            xlabel(app.precipitation_graph, 'timestamp')
            ylabel(app.precipitation_graph, 'Precipitation (mm)')
            zlabel(app.precipitation_graph, 'Z')
            app.windSpeed_graph = uiaxes(app.realtimeDetailedData);
            title(app.windSpeed_graph, 'Daily Wind Speed')
            xlabel(app.windSpeed_graph, 'timestamp')
            ylabel(app.windSpeed_graph, 'Wind speed (km/h)')
            zlabel(app.windSpeed_graph, 'Z')
            app.windSpeed_graph.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.lbl_addr = uilabel(app.realtimeDetailedData);
            app.lbl_addr.Text = 'N/A';
            app.curr_tempCaption = uilabel(app.realtimeDetailedData);
            app.curr_tempCaption.Text = 'Temperature(°F):';
            app.curr_humidityCaption = uilabel(app.realtimeDetailedData);
            app.curr_humidityCaption.Text = 'Humidity(%):';
            app.curr_precipCaption = uilabel(app.realtimeDetailedData);
            app.curr_precipCaption.Text = 'Precipitation(mm):';
            app.curr_windspeedCaption = uilabel(app.realtimeDetailedData);
            app.curr_windspeedCaption.Text = 'Wind(km/h):';
            app.curr_tempVal = uilabel(app.realtimeDetailedData);
            app.curr_tempVal.Text = 'N/A';
            app.curr_humidityVal = uilabel(app.realtimeDetailedData);
            app.curr_humidityVal.Text = 'N/A';
            app.curr_precipVal = uilabel(app.realtimeDetailedData);
            app.curr_precipVal.Text = 'N/A';
            app.curr_windspeedVal = uilabel(app.realtimeDetailedData);
            app.curr_windspeedVal.Text = 'N/A';
            app.realtimeSummaryData = uipanel(app.UIFigure);
            app.realtimeSummaryData.Title = 'Today''s weather';
            app.tempVal = uilabel(app.realtimeSummaryData);
            app.tempVal.Text = 'N/A';
            app.weathericon = uiimage(app.realtimeSummaryData);
            app.weathericon.ImageSource = fullfile(pathToMLAPP, 'public', 'icons', 'PNG', '1st Set - Color', 'clear-day.png');
            app.descriptionVal = uilabel(app.realtimeSummaryData);
            app.descriptionVal.Text = 'N/A';
            app.detail_info = uipanel(app.realtimeSummaryData);
            app.feelslikeCaption = uilabel(app.detail_info);
            app.feelslikeCaption.Text = 'Feels Like(°F):';
            app.humidityCaption = uilabel(app.detail_info);
            app.humidityCaption.Text = 'Humidity(%):';
            app.precipCaption = uilabel(app.detail_info);
            app.precipCaption.Text = 'Precipitaion(mm):';
            app.windspeedCaption = uilabel(app.detail_info);
            app.windspeedCaption.Text = 'Wind Speed(km/h):';
            app.sunriseCaption = uilabel(app.detail_info);
            app.sunriseCaption.Text = 'Sunrise:';
            app.sunsetCaption = uilabel(app.detail_info);
            app.sunsetCaption.Text = 'Sunset:';
            app.feelslikeVal = uilabel(app.detail_info);
            app.feelslikeVal.Text = 'N/A';
            app.humidityVal = uilabel(app.detail_info);
            app.humidityVal.Text = 'N/A';
            app.precipVal = uilabel(app.detail_info);
            app.precipVal.Text = 'N/A';
            app.windspeedVal = uilabel(app.detail_info);
            app.windspeedVal.Text = 'N/A';
            app.sunriseVal = uilabel(app.detail_info);
            app.sunriseVal.Text = 'N/A';
            app.sunsetVal = uilabel(app.detail_info);
            app.sunsetVal.Text = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end